classdef SmartPixelProcessor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        ContrastValueLabel        matlab.ui.control.Label
        BrightnessValueLabel      matlab.ui.control.Label
        FormulaLabel              matlab.ui.control.Label
        StatusLabel               matlab.ui.control.Label
        LoadImageButton           matlab.ui.control.Button
        ResetButton               matlab.ui.control.Button
        SaveImageButton           matlab.ui.control.Button
        EdgeMethodDropDown        matlab.ui.control.DropDown
        EdgeMethodDropDownLabel   matlab.ui.control.Label
        EdgeDetectionSwitch       matlab.ui.control.ToggleSwitch
        EdgeDetectionSwitchLabel  matlab.ui.control.Label
        GrayscaleSwitch           matlab.ui.control.ToggleSwitch
        GrayscaleSwitchLabel      matlab.ui.control.Label
        ContrastSlider            matlab.ui.control.Slider
        ContrastSliderLabel       matlab.ui.control.Label
        BrightnessSlider          matlab.ui.control.Slider
        BrightnessSliderLabel     matlab.ui.control.Label
        HistogramAxes             matlab.ui.control.UIAxes
        OriginalImageAxes         matlab.ui.control.UIAxes
        ProcessedImageAxes        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        OriginalImage
        ProcessedImage
    end

    
    methods (Access = private)
    
    function UpdateImageAndDisplay(app)
        % מנגנון הגנה: אם המשתמש עדיין לא טען תמונה, אל תבצע שום חישוב
        if isempty(app.OriginalImage)
            return;
        end
        
        % אנחנו תמיד מתחילים את שרשרת העיבוד מחדש מתוך העתק של התמונה המקורית
        img = app.OriginalImage;
        currentOp = 'Viewing Mode: Original Image'; % טקסט ברירת המחדל ללייבל הנוסחאות
        
        % סעיף 1: בדיקת מצב המתג של הפיכה לשחור-לבן (Grayscale)
        if strcmp(app.GrayscaleSwitch.Value, 'On')
            if size(img, 3) == 3 % המרה תתבצע רק אם התמונה הנוכחית היא אכן צבעונית
                img = rgb2gray(img);
                % נוסחת ה-ITU המשמשת את מטלאב לשקלול ערוצי הצבע לפי רגישות העין האנושית
                currentOp = 'Grayscale Conversion: I = 0.299*R + 0.587*G + 0.114*B';
            end
        end
        
        % סעיף 2: עיבוד בהירות (Brightness) וניגודיות (Contrast)
        % המרה לפורמט double הכרחית כדי למנוע חריגות בחישובים (מתחת ל-0 או מעל 255)
        img_double = double(img);
        b = app.BrightnessSlider.Value; % משיכת ערך ההיסט (b) מהסליידר של הבהירות
        c = 1 + (app.ContrastSlider.Value / 50); % חישוב פקטור השיפוע (c) מהסליידר של הניגודיות
        
        % עדכון תוויות הערך המספרי של הסליידרים בממשק בזמן אמת
        app.BrightnessValueLabel.Text = sprintf('%d', round(b));
        app.ContrastValueLabel.Text = sprintf('%d', round(app.ContrastSlider.Value));

        % ביצוע הטרנספורמציה הליניארית רק אם המשתמש אכן הזיז את אחד הסליידרים
        if b ~= 0 || app.ContrastSlider.Value ~= 0
            img_double = img_double .* c + b; % החלת משוואת קו ישר על כל פיקסל במטריצה
            currentOp = sprintf('Linear Transformation: I_out = %.2f * I_in + (%.0f)', c, b);
        end
        
        % מנגנון קטימה (Clipping): הגבלת ערכים שחרגו מהטווח החוקי והחזרה לפורמט תמונה uint8
        img_double(img_double > 255) = 255;
        img_double(img_double < 0) = 0;
        img = uint8(img_double);
        
        % סעיף 3: בדיקת מצב המתג של גילוי גבולות (Edge Detection)
        if strcmp(app.EdgeDetectionSwitch.Value, 'On')
            % פונקציית edge במטלאב מחייבת מטריצה דו-ממדית (שחור-לבן)
            if size(img, 3) == 3
                edge_img = rgb2gray(img);
            else
                edge_img = img;
            end
            
            % משיכת סוג האופרטור שנבחר בתפריט (כמו Canny או Sobel) והפעלתו
            method = lower(app.EdgeMethodDropDown.Value); 
            img = edge(edge_img, method); % הפלט הוא מטריצה לוגית (0 או 1)
            img = uint8(img) * 255; % המרה חזרה לטווח של 0 או 255 כדי שיוצג בבירור על המסך
            
            currentOp = sprintf('Edge Detection: %s Operator (2D Gradient Computation)', app.EdgeMethodDropDown.Value);
        end
        
        % עדכון תווית הטקסט של הנוסחה הדינמית בממשק בהתאם לעיבוד הפעיל
        app.FormulaLabel.Text = currentOp;
        
        % שמירת התוצאה הסופית בזיכרון והצגתה על גבי תשתית הגרף המרכזית
        app.ProcessedImage = img;
        imshow(app.ProcessedImage, 'Parent', app.ProcessedImageAxes);

        % סעיף 4: חישוב ועדכון עקומת ההיסטוגרמה (הגרף השמאלי)
        cla(app.HistogramAxes); % ניקוי של שאריות הגרף הקודם מהתצוגה
        hold(app.HistogramAxes, 'on'); % החזקת מערכת הצירים פתוחה לצורך שרטוט מספר גרפים
        
        if size(app.ProcessedImage, 3) == 3 % אם התמונה הנוכחית היא תמונת צבע (RGB)
            % חילוץ וחישוב וקטורי התפלגות רמות הבהירות לכל ערוץ צבע בנפרד
            [countsR, binR] = imhist(app.ProcessedImage(:,:,1));
            [countsG, binG] = imhist(app.ProcessedImage(:,:,2));
            [countsB, binB] = imhist(app.ProcessedImage(:,:,3));
            
            % שרטוט שלוש עקומות בצבעים תואמים על גבי אותו הגרף
            plot(app.HistogramAxes, binR, countsR, 'r', 'LineWidth', 1.5);
            plot(app.HistogramAxes, binG, countsG, 'g', 'LineWidth', 1.5);
            plot(app.HistogramAxes, binB, countsB, 'b', 'LineWidth', 1.5);
        else % אם התמונה הנוכחית היא חד-ערוצית (שחור-לבן או תמונת גבולות)
            [counts, bin] = imhist(app.ProcessedImage);
            % שרטוט עקומה יחידה בצבע שחור
            plot(app.HistogramAxes, bin, counts, 'k', 'LineWidth', 1.5);
        end
        
        % הגבלת ציר ה-X לטווח הבהירות המקסימלי של תמונת 8-ביט
        xlim(app.HistogramAxes, [0 255]);
        
        % הוספת רשת מדעית ברקע הגרף להקלה על קריאת הנתונים
        grid(app.HistogramAxes, 'on');

        hold(app.HistogramAxes, 'off'); % שחרור נעילת מערכת הצירים של הגרף
    end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
        
    % פתיחת תיבת דו-שיח של מערכת ההפעלה לבחירת קובץ תמונה מהמחשב
    [file, path] = uigetfile({'*.jpg;*.jpeg;*.png;*.bmp', 'Image Files'});
    if isequal(file, 0)
        return; % אם המשתמש התחרט ולחץ על ביטול, הפונקציה נעצרת כאן
    end
    
    % יצירת חלון התקדמות (Progress Dialog) כדי להראות למשתמש שהמערכת עובדת
    d = uiprogressdlg(app.UIFigure, 'Title', 'Loading File', ...
        'Message', 'Reading image data...', 'Indeterminate', 'on');
    
    % חיבור נתיב התיקייה ושם הקובץ לנתיב מלא, וקריאת המטריצה של התמונה לזיכרון
    fullFileName = fullfile(path, file);
    app.OriginalImage = imread(fullFileName);
    app.ProcessedImage = app.OriginalImage; % בשלב הטעינה, התמונה המעובדת זהה למקור
    
    % חילוץ מאפייני המטריצה: גובה (שורות), רוחב (עמודות) ומספר ערוצי צבע
    [height, width, channels] = size(app.OriginalImage);
    if channels == 3
        imgType = 'Color (RGB)'; % אם יש 3 ערוצים, זו תמונת צבע (אדום, ירוק, כחול)
    else
        imgType = 'Grayscale'; % אם יש ערוץ אחד, זו תמונת רמות אפור
    end
    
    % עדכון שורת הסטטוס הדינמית שבנינו בתחתית הממשק עם נתוני הקובץ האמיתיים
    app.StatusLabel.Text = sprintf('  File: %s  |  Resolution: %d x %d  |  Type: %s', ...
    file, width, height, imgType);
    
    % הצגת תמונת המקור הלא-נגועה על גבי תשתית הגרף הימנית (Reference)
    imshow(app.OriginalImage, 'Parent', app.OriginalImageAxes);
    
    % עדכון הטקסט בחלונית הטעינה רגע לפני שהיא נסגרת
    d.Message = 'Refreshing display panel...';
    
    % קריאה לפונקציית העזר המרכזית (המוח) כדי שתעבד ותציג את התמונה וההיסטוגרמה
    app.UpdateImageAndDisplay();
    
    % סגירה והסרה של חלונית הטעינה מהמסך
    close(d);
    
        end

        % Button pushed function: SaveImageButton
        function SaveImageButtonPushed(app, event)
        % מנגנון הגנה: בדיקה אם בכלל קיימת תמונה מעובדת בזיכרון של האפליקציה
        if isempty(app.ProcessedImage)
        % אם אין תמונה, נפתח חלונית התראה (Alert) כדי שהתוכנה לא תקרוס
        uialert(app.UIFigure, 'No processed image to save!', 'Error');
        return;
    end
    
    % פתיחת חלון שמירה של מערכת ההפעלה המאפשר למשתמש לבחור שם קובץ ופורמט (PNG/JPG)
        [file, path] = uiputfile({'*.png';'*.jpg';'*.bmp'}, 'Save Image As');
        if isequal(file, 0)
        return; % אם המשתמש התחרט ולחץ על ביטול, הפונקציה נעצרת ולא שומרת
    end
    
    % הפקודה imwrite לוקחת את מטריצת הפיקסלים המעובדת מהזיכרון ושומרת אותה כקובץ אמיתי במחשב
        imwrite(app.ProcessedImage, fullfile(path, file));
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
     % מנגנון הגנה: אם המשתמש עדיין לא טען תמונה, אין מה לאפס
    if isempty(app.OriginalImage)
        return;
    end
    
    % החזרת כל רכיבי הבקרה בממשק (הסליידרים והמתגים) פיזית לערכי ברירת המחדל שלהם
        app.BrightnessSlider.Value = 0;   % החזרת הבהירות ל-0
        app.ContrastSlider.Value = 0;     % החזרת הניגודיות ל-0
        app.GrayscaleSwitch.Value = 'Off'; % כיבוי מצב שחור-לבן
        app.EdgeDetectionSwitch.Value = 'Off'; % כיבוי מצב גילוי גבולות
      
    % קריאה לפונקציית העזר המרכזית כדי שתחשב ותציג את התמונה מחדש ללא האפקטים
        app.UpdateImageAndDisplay();   
        end

        % Value changed function: BrightnessSlider
        function BrightnessSliderValueChanged(app, event)
            value = app.BrightnessSlider.Value;
            app.UpdateImageAndDisplay();
        end

        % Value changed function: ContrastSlider
        function ContrastSliderValueChanged(app, event)
            value = app.ContrastSlider.Value;
            app.UpdateImageAndDisplay();
        end

        % Value changed function: GrayscaleSwitch
        function GrayscaleSwitchValueChanged(app, event)
            value = app.GrayscaleSwitch.Value;
            app.UpdateImageAndDisplay();
        end

        % Value changed function: EdgeMethodDropDown
        function EdgeMethodDropDownValueChanged(app, event)
            value = app.EdgeMethodDropDown.Value;
            app.UpdateImageAndDisplay();
        end

        % Value changed function: EdgeDetectionSwitch
        function EdgeDetectionSwitchValueChanged(app, event)
            value = app.EdgeDetectionSwitch.Value;
            app.UpdateImageAndDisplay();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ProcessedImageAxes = uiaxes(app.UIFigure);
            title(app.ProcessedImageAxes, 'Processed Image')
            zlabel(app.ProcessedImageAxes, 'Z')
            app.OriginalImageAxes = uiaxes(app.UIFigure);
            title(app.OriginalImageAxes, 'Original Reference')
            zlabel(app.OriginalImageAxes, 'Z')
            app.HistogramAxes = uiaxes(app.UIFigure);
            title(app.HistogramAxes, 'Histogram Analysis')
            xlabel(app.HistogramAxes, 'Pixel Intensity (0-255)')
            ylabel(app.HistogramAxes, 'Pixel Count')
            zlabel(app.HistogramAxes, 'Z')
            app.BrightnessSliderLabel = uilabel(app.UIFigure);
            app.BrightnessSliderLabel.Text = 'Brightness';
            app.BrightnessSlider = uislider(app.UIFigure);
            app.BrightnessSlider.Limits = [-100 100];
            app.BrightnessSlider.ValueChangedFcn = createCallbackFcn(app, @BrightnessSliderValueChanged, true);
            app.ContrastSliderLabel = uilabel(app.UIFigure);
            app.ContrastSliderLabel.Text = 'Contrast';
            app.ContrastSlider = uislider(app.UIFigure);
            app.ContrastSlider.Limits = [-50 50];
            app.ContrastSlider.ValueChangedFcn = createCallbackFcn(app, @ContrastSliderValueChanged, true);
            app.GrayscaleSwitchLabel = uilabel(app.UIFigure);
            app.GrayscaleSwitchLabel.Text = 'Grayscale';
            app.GrayscaleSwitch = uiswitch(app.UIFigure, 'toggle');
            app.GrayscaleSwitch.ValueChangedFcn = createCallbackFcn(app, @GrayscaleSwitchValueChanged, true);
            app.EdgeDetectionSwitchLabel = uilabel(app.UIFigure);
            app.EdgeDetectionSwitchLabel.Text = 'Edge Detection';
            app.EdgeDetectionSwitch = uiswitch(app.UIFigure, 'toggle');
            app.EdgeDetectionSwitch.ValueChangedFcn = createCallbackFcn(app, @EdgeDetectionSwitchValueChanged, true);
            app.EdgeMethodDropDownLabel = uilabel(app.UIFigure);
            app.EdgeMethodDropDownLabel.Text = 'Edge Method';
            app.EdgeMethodDropDown = uidropdown(app.UIFigure);
            app.EdgeMethodDropDown.Items = {'Canny', 'Sobel'};
            app.EdgeMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @EdgeMethodDropDownValueChanged, true);
            app.EdgeMethodDropDown.Value = 'Canny';
            app.SaveImageButton = uibutton(app.UIFigure, 'push');
            app.SaveImageButton.ButtonPushedFcn = createCallbackFcn(app, @SaveImageButtonPushed, true);
            app.SaveImageButton.Text = 'Save Image';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset to Default';
            app.LoadImageButton = uibutton(app.UIFigure, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.StatusLabel = uilabel(app.UIFigure);
            app.StatusLabel.Text = 'Waiting for image selection...';
            app.FormulaLabel = uilabel(app.UIFigure);
            app.FormulaLabel.Text = 'Viewing Mode: Original Image';
            app.BrightnessValueLabel = uilabel(app.UIFigure);
            app.BrightnessValueLabel.Text = '0';
            app.ContrastValueLabel = uilabel(app.UIFigure);
            app.ContrastValueLabel.Text = '0';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SmartPixelProcessor

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end